classdef ImageHistogramsAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextArea                       matlab.ui.control.TextArea
        Label_6                        matlab.ui.control.Label
        ChoiceofSegmentationMaskLabel  matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        ClicktobeginsegmentationonceimageisloadedLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        SelectMaskDropDown             matlab.ui.control.DropDown
        SelectMaskDropDownLabel        matlab.ui.control.Label
        FinalResultsLabel              matlab.ui.control.Label
        ResultsLabel                   matlab.ui.control.Label
        LoadButton_ConfirmChoice       matlab.ui.control.Button
        LoadButton_StartDiag           matlab.ui.control.Button
        LoadButton_LoadIm              matlab.ui.control.Button
        Label_5                        matlab.ui.control.Label
        ImageAxes_kMeans               matlab.ui.control.UIAxes
        ImageAxes_RegionGrowing        matlab.ui.control.UIAxes
        ImageAxes_Snake                matlab.ui.control.UIAxes
        ImageAxes_AT                   matlab.ui.control.UIAxes
        ImageAxes_Otsu                 matlab.ui.control.UIAxes
        ImageAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        loadedImage     % Original uploaded image
        selectedMask   % Chosen mask to move forward with
        selectedRgbMask
    end

    methods (Access = private)
        
        function updateimage(app,imagefile)
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);  
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
        end


        % ---------------- PREPROCESSING METHODS ---------------------
        
        function imgOut = preprocessGrayscale(~, img)
            imgGray = rgb2gray(img);
            % Median filter
            imgMed = medfilt2(imgGray, [5 5]);
            % Morphological Closing
            imgClosed = imclose(imgMed, strel('disk', 3));
            % Gaussian filter
            sigma = 1.2;
            g_filt_length = 9; 
            G = fspecial('gaussian', [g_filt_length g_filt_length], sigma);
            imgSmooth = conv2(double(imgClosed), G, 'same');
            figure('Name', 'Final Preprocessed Image (Grayscale)', 'NumberTitle', 'off');
            imshow(uint8(imgSmooth));
            title('Final Preprocessed Image (Grayscale)');
            imgOut = imgSmooth;
        end
        
        function imgOut = preprocessColor(~, img)
            % Median Filter
            imgMed = zeros(size(img), 'like', img);
            for c = 1:3
                imgMed(:,:,c) = medfilt2(img(:,:,c), [5 5]);
            end
            % Convert to Lab color space
            labMed = rgb2lab(imgMed); 
            L = labMed(:,:,1);
            % Morphological Closing on L* channel
            se = strel('disk', 3);
            L_closed = imclose(L, se);
            % Reconstruct Lab image with updated L*
            labEnhanced = labMed;
            labEnhanced(:,:,1) = L_closed;
            % Convert back to RGB
            rgbEnhanced = lab2rgb(labEnhanced);
            rgbEnhanced = im2uint8(rgbEnhanced);
            % Gaussian smoothing on each RGB channel
            sigma = 1;
            g_filt_length = 4;
            G = fspecial('gaussian', [g_filt_length g_filt_length], sigma);
            imgSmooth = zeros(size(rgbEnhanced), 'like', rgbEnhanced);
            for c = 1:3
                imgSmooth(:,:,c) = conv2(double(rgbEnhanced(:,:,c)), G, 'same');
            end
            figure('Name', 'Final Preprocessed Image (Color)', 'NumberTitle', 'off');
            imshow(imgSmooth);
            title('Final Preprocessed Image (Color)');
            imgOut = imgSmooth;
        end

        % ---------------- SEGMENTATION METHODS ----------------------

        function mask = segmentOtsu(app, grayImg, cropBox, fullSize)
            normImg = uint8(mat2gray(grayImg)*255);
            level = graythresh(normImg);
            bw = imbinarize(normImg, level);
            bw = imcomplement(bw);
            bw = bwareafilt(bw, 1);
            bw = imfill(bw,'holes');
            mask = app.reconstructFullMask(bw, cropBox, fullSize);
        end
        
        function mask = segmentAdaptiveThreshold(app, grayImg, cropBox, fullSize)
            [croppedRows, croppedCols] = size(grayImg);
            lowerBound = min(croppedRows,croppedCols)*0.5;
            upperBound = max(croppedRows,croppedCols)*0.07;
            blockSize = round(min(upperBound,lowerBound));
            overlap = round(blockSize / 1.3); % ~75% overlap
            step = blockSize - overlap;
            % Initialize accumulation maps
            iAdaptiveSum = zeros(size(grayImg));
            weightMap = zeros(size(grayImg));
            % Only iterate over pixels within the cropped region (ROI)
            maxRowStart = croppedRows - blockSize + 1;
            maxColStart = croppedCols - blockSize + 1;

            for r = 1:step:maxRowStart
                for c = 1:step:maxColStart
                    % Define block bounds
                    r_end = r + blockSize - 1;
                    c_end = c + blockSize - 1;
                    block = grayImg(r:r_end, c:c_end);
            
                    % Normalize and compute threshold
                    blockNorm = uint8(mat2gray(block)*255);
                    thresh = graythresh(blockNorm);
                    bwBlock = imbinarize(blockNorm, thresh);
                    bwBlock = imcomplement(bwBlock);  % Assume lesion is darker
          
                    % Add to accumulation maps
                    iAdaptiveSum(r:r_end, c:c_end) = iAdaptiveSum(r:r_end, c:c_end) + double(bwBlock);
                    weightMap(r:r_end, c:c_end) = weightMap(r:r_end, c:c_end) + 1;
                end
            end

            % Avoid divide-by-zero
            weightMap(weightMap == 0) = 1;
            iAdaptiveAvg = iAdaptiveSum ./ weightMap;
            iAdaptive = iAdaptiveAvg > 0.5;
            % Clean and fill mask
            imgFilled = imfill(iAdaptive, 'holes');
            imgCleaned = bwareafilt(imgFilled, 1);
            mask = app.reconstructFullMask(imgCleaned, cropBox, fullSize); 
        end

        function mask = segmentSnake(app, grayImg, cropBox, fullSize, initMask)
            [rows, cols] = size(grayImg);
            numPixels = rows * cols;
            % Adjust iterations based on image size
            baseIterations = 190;
            scalingFactor = 0.005;
            iterations = round(baseIterations + numPixels * scalingFactor);
            iterations = min(iterations, 1500);  % Clamp
            % Run active contour using Chan-Vese model
            lesionMask = activecontour(grayImg, initMask, iterations);
            mask = app.reconstructFullMask(lesionMask, cropBox, fullSize);
        end

        
        function mask = segmentRegionGrowing(app, colorImg, cropBox, fullSize, roiLocal)
            % Convert to Lab
            labIm = rgb2lab(im2double(colorImg));
            % Region growing in L*a*b* + spatial space
            L = labIm(:,:,1);
            L_std = std(L(:));
            L_tol = round(5.5 * L_std);
            L_tol = min(max(L_tol, 15), 180);
            lesionMask = regionGrowLab(labIm, roiLocal, L_tol, 0);
            % Morphological post-processing
            lesionMask = bwconvhull(lesionMask, 'objects');
            lesionMask = imclose(lesionMask, strel('disk', 3));
            lesionMask = imopen(lesionMask, strel('disk', 1));
            lesionMask = bwareaopen(lesionMask, 10);
            mask = app.reconstructFullMask(lesionMask, cropBox, fullSize);
        end
        

        function mask = segmentKmeans(app, colorImg, cropBox, fullSize)
            % Convert image to Lab
            labImg = rgb2lab(im2double(colorImg));
            % Run external function
            k = 3;
            maxIters = 30;
            convergenceThresh = 1;
            pixel_labels = segmentKmeansAB(labImg, k, maxIters, convergenceThresh);
            % Step 4: Pick darkest cluster based on median L*
            L = labImg(:,:,1);
            medians = zeros(1, k);
            for i = 1:k
                medians(i) = median(L(pixel_labels == i));
            end
            [~, darkestIdx] = min(medians);
            lesionMask = (pixel_labels == darkestIdx);
            % Clean mask
            lesionMask = imfill(lesionMask, 'holes');
            lesionMask = imclose(lesionMask, strel('disk', 2));
            lesionMask = bwareaopen(lesionMask, 20);
            % Reconstruct full-size mask
            mask = app.reconstructFullMask(lesionMask, cropBox, fullSize);
        end


        % ---------------- FEATURE EXTRACTION METHODS ----------------------
        % (1) Assymetry
        function AI = computeAsymmetryIndex(app, mask)
           % Compute average asymmetry index (AI) from horizontal and vertical flip comparison
           % Input: full-size binary lesion mask
           % Output: AI (0–100)
        
           % Crop to lesion bounding box
           stats = regionprops(mask, 'BoundingBox', 'Area');
           if isempty(stats)
               AI = 0;
               return;
           end
        
           bbox = round(stats(1).BoundingBox);
           subMask = mask(bbox(2):bbox(2)+bbox(4)-1, ...
                          bbox(1):bbox(1)+bbox(3)-1);
        
           % Center and align
           statsSub = regionprops(subMask, 'Centroid', 'Orientation', 'Area');
           if isempty(statsSub) || statsSub.Area == 0
               AI = 0;
               return;
           end
        
           [rows, cols] = size(subMask);
           dx = cols/2 - statsSub.Centroid(1);
           dy = rows/2 - statsSub.Centroid(2);
           centered = imtranslate(subMask, [dx, dy]);
        
           angle = -statsSub.Orientation;
           aligned = imrotate(centered, angle, 'crop');
        
           % Flip and compare
           flippedH = fliplr(aligned);
           flippedV = flipud(aligned);
        
           xorH = xor(flippedH, aligned);
           xorV = xor(flippedV, aligned);
        
           AI_H = nnz(xorH) / statsSub.Area;
           AI_V = nnz(xorV) / statsSub.Area;
        
           % Return average asymmetry index as percentage
           AI = 100 * (AI_H + AI_V) / 2;
        end

        % (3) Color 
        function n = estimateColorCount(app, rgbMasked)
            % Estimate number of perceptually distinct general colors in RGB-masked lesion
        
            % Extract non-black pixels (i.e. where lesion exists)
            mask = any(rgbMasked > 0, 3);  % Logical mask of non-zero pixels
            lesionPixels = reshape(rgbMasked, [], 3);
            lesionPixels = lesionPixels(mask(:), :);
        
            % Convert to Lab space for perceptual clustering
            lesionLab = rgb2lab(double(lesionPixels) / 255);  % Normalize before rgb2lab
            ab = lesionLab(:, 2:3);  % Use a*, b* channels
        
            % Skip if too few pixels
            if size(ab,1) < 50
                n = 1;
                return;
            end
        
            try
                K = 6;  % Max number of clusters to try
                [idx, C] = kmeans(ab, K, 'MaxIter', 100, 'Replicates', 5, 'EmptyAction', 'drop');
        
                % Merge clusters that are close in color space (perceptually similar)
                % Compute pairwise distances between cluster centers
                D = pdist2(C, C);
                % Ignore self-distance
                D(logical(eye(size(D)))) = Inf;
        
                % Define stricter minimum perceptual distance threshold
                minDistThreshold = 7.5;
        
                % Initialize merged clusters count as total clusters found
                mergedClusters = 1: size(C,1);
                toMerge = false(size(C,1), size(C,1));
        
                % Find pairs to merge based on distance < threshold
                [row, col] = find(D < minDistThreshold);
                for i = 1:length(row)
                    % Mark cluster pairs for merging
                    toMerge(row(i), col(i)) = true;
                    toMerge(col(i), row(i)) = true;
                end
        
                % Merge clusters that are close into groups (connected components)
                G = graph(toMerge);
                bins = conncomp(G);
        
                % The number of unique connected components is the number of distinct colors
                n = max(bins);
        
                % Ensure minimum of 1
                n = max(n, 1);
        
            catch
                n = 1;
            end
        end


        % ---------------- HELPER METHODS (UTILS) -----------------------------
        function fullMask = reconstructFullMask(~, croppedMask, cropBox, fullSize)
            fullMask = false(fullSize(1), fullSize(2));
            fullMask(cropBox(2):cropBox(2)+cropBox(4)-1, ...
                     cropBox(1):cropBox(1)+cropBox(3)-1) = croppedMask;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'Melanoma6.jpg');
            app.loadedImage = imread('Melanoma6.jpg');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image
            updateimage(app, app.SelectMaskDropDown.Value);
        end

        % Button pushed function: LoadButton_LoadIm
        function LoadButton_LoadImPushed(app, event)
            clc; close all;
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg'}, 'Select Skin Lesion Image');
            if isequal(file, 0)
                return;
            end
            app.loadedImage = imread(fullfile(path, file));
            imshow(app.loadedImage, 'Parent', app.ImageAxes);  % Show in UIAxes
        end

        % Button pushed function: LoadButton_StartDiag
        function LoadButton_StartDiagPushed(app, event)
            % Safety check
            if isempty(app.loadedImage)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            im = app.loadedImage;
            % ROI Selection
            figure, imshow(im); title('Draw ROI Around Lesion, then double click to finish.');
            roi = drawfreehand(); wait(roi);
            roiMask = createMask(roi);
            props = regionprops(roiMask, 'BoundingBox');
            cropBox = round(props(1).BoundingBox);
        
            % Crop and Preprocess
            imgCrop = im(cropBox(2):cropBox(2)+cropBox(4)-1, ...
                         cropBox(1):cropBox(1)+cropBox(3)-1, :);
            roiLocal = roiMask(cropBox(2):(cropBox(2)+cropBox(4)-1), ...
                   cropBox(1):(cropBox(1)+cropBox(3)-1));
            imgGray = app.preprocessGrayscale(imgCrop);
            imgColor = app.preprocessColor(imgCrop);
        
            % Segmentations
            maskOtsu     = app.segmentOtsu(imgGray, cropBox, size(im));
            maskedRgbOtsu = bsxfun(@times, im, cast(maskOtsu, 'like', im));
            imshow(maskedRgbOtsu, 'Parent', app.ImageAxes_Otsu);

            maskAdaptive = app.segmentAdaptiveThreshold(imgGray, cropBox, size(im));
            maskedRgbAdaptive = bsxfun(@times, im, cast(maskAdaptive, 'like', im));
            imshow(maskedRgbAdaptive, 'Parent', app.ImageAxes_AT);

            maskSnake    = app.segmentSnake(imgGray, cropBox, size(im), roiLocal);
            maskedRgbSnake = bsxfun(@times, im, cast(maskSnake, 'like', im));
            imshow(maskedRgbSnake, 'Parent', app.ImageAxes_Snake);

            maskRegion   = app.segmentRegionGrowing(imgColor, cropBox, size(im), roiLocal);
            maskedRgbRegion = bsxfun(@times, im, cast(maskRegion, 'like', im));
            imshow(maskedRgbRegion, 'Parent', app.ImageAxes_RegionGrowing);
            
            maskKmeans   = app.segmentKmeans(imgColor, cropBox, size(im));
            maskedRgbKMeans = bsxfun(@times, im, cast(maskKmeans , 'like', im));
            imshow(maskedRgbKMeans, 'Parent', app.ImageAxes_kMeans);
            
            choice = app.SelectMaskDropDown.Value;
            switch choice
                case "Otsu's Method"
                    app.selectedMask = maskOtsu;
                    app.selectedRgbMask = maskedRgbOtsu;
                case "Adaptive Thresholding"
                    app.selectedMask = maskAdaptive;
                    app.selectedRgbMask = maskedRgbAdaptive;
                case "Snake (Active Contour)"
                    app.selectedMask = maskSnake;
                    app.selectedRgbMask = maskedRgbSnake;
                case "Region Growing"
                    app.selectedMask = maskRegion;
                    app.selectedRgbMask = maskedRgbRegion;
                case "K Means Clustering"
                    app.selectedMask = maskKmeans;
                    app.selectedRgbMask = maskedRgbKMeans;
                otherwise
                    uialert(app.UIFigure, 'Invalid selection.', 'Error');
                    return;
            end

        end

        % Button pushed function: LoadButton_ConfirmChoice
        function LoadButton_ConfirmChoicePushed(app, event)
            
            % Feature Extraction
            stats=regionprops(app.selectedMask,'all');
            allAreas=[stats.Area];
            [maxArea,maxindx]=max(allAreas);
            allPerimeters = [stats.Perimeter];
            maxPerimeter = max(allPerimeters);

            % A - Assymetry
            AI = app.computeAsymmetryIndex(app.selectedMask);
            hasShapeAsym = AI > 8;

            % (A) 
            hasInternalAsym = AI > 16;

            % B: Abrupt border cutoff
            edgePixels = edge(app.selectedMask, 'sobel');
            borderAbrupt = sum(edgePixels(:)) / nnz(app.selectedMask);
            hasBorderCutoff = borderAbrupt >= 0.1;  % Empirical threshold
            % B: Circularity
            Circularity = maxPerimeter  .^ 2 ./ (4 * pi* maxArea);
            hasPoorCircularity = Circularity >= 1.5;

            % C: Net distance
            Ilab = rgb2lab(app.selectedRgbMask);
            Lchannel = Ilab(:,:,1);
            Achannel = Ilab(:,:,2);
            Bchannel = Ilab(:,:,3);
            % Use only lesion pixels
            L_lesion = Lchannel(app.selectedMask);
            A_lesion = Achannel(app.selectedMask);
            B_lesion = Bchannel(app.selectedMask);
            Lmean = mean(L_lesion);
            Amean = mean(A_lesion);
            Bmean = mean(B_lesion);
            deltaL = L_lesion - Lmean;
            deltaA = A_lesion - Amean;
            deltaB = B_lesion - Bmean;
            deltaE = sqrt(deltaL .^ 2 + deltaA .^ 2 + deltaB .^ 2);
            meandeltaE = mean2(deltaE);
            hasGreatColorVar = meandeltaE >= 7.0;
            % C: Number of distinct colors (surrogate for diff structures)
            nColors = app.estimateColorCount(app.selectedRgbMask);
            has3Colors = nColors >= 3;

            % Final classification score using ABC-point-like system
            score = hasShapeAsym + hasInternalAsym ...
            + hasPoorCircularity + has3Colors + 0.75*hasGreatColorVar;

            % Diagnosis logic
            if score >= 3
                diagnosis = 'Likely Malignant (Melanoma)';
            elseif score >= 2
                diagnosis = 'Suspicious: Needs Dermatologist Review.';
            else
                diagnosis = 'Likely Benign';
            end
            
            % Display Results
            resultsStr = sprintf([
            'Feature Scores:\n', ...
            'Asymmetry Index: %.2f (Threshold 10) → %s\n', ...
            'Internal Asymmetry: %.2f (Threshold 20) → %s\n', ...
            'Circularity: %.2f (Threshold 1.5) → %s\n', ...
            'Number of Colors: %d (Threshold 3) → %s\n', ...
            'Mean Color Variation (ΔE): %.2f (Threshold 2.3) → %s\n\n', ...
            'Final Score: %d\n', ...
            'Diagnosis: %s'], ...
            AI, bool2str(hasShapeAsym), ...
            AI, bool2str(hasInternalAsym), ...
            Circularity, bool2str(hasPoorCircularity), ...
            nColors, bool2str(has3Colors), ...
            meandeltaE, bool2str(hasGreatColorVar), ...
            score, diagnosis);
            
            % Display in the app label
            app.TextArea.Value = splitlines(resultsStr);
            
            function str = bool2str(b)
                if b
                    str = 'Yes';
                else
                    str = 'No';
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxes_Otsu = uiaxes(app.UIFigure);
            title(app.ImageAxes_Otsu, 'Otsu''s Method')
            app.ImageAxes_Otsu.XTick = [];
            app.ImageAxes_Otsu.XTickLabel = {'[ ]'};
            app.ImageAxes_Otsu.YTick = [];
            app.ImageAxes_AT = uiaxes(app.UIFigure);
            title(app.ImageAxes_AT, 'Adaptive Thresholding')
            app.ImageAxes_AT.XTick = [];
            app.ImageAxes_AT.XTickLabel = {'[ ]'};
            app.ImageAxes_AT.YTick = [];
            app.ImageAxes_Snake = uiaxes(app.UIFigure);
            title(app.ImageAxes_Snake, 'Snake (Active Contour)')
            app.ImageAxes_Snake.XTick = [];
            app.ImageAxes_Snake.XTickLabel = {'[ ]'};
            app.ImageAxes_Snake.YTick = [];
            app.ImageAxes_RegionGrowing = uiaxes(app.UIFigure);
            title(app.ImageAxes_RegionGrowing, 'Region Growing')
            app.ImageAxes_RegionGrowing.XTick = [];
            app.ImageAxes_RegionGrowing.XTickLabel = {'[ ]'};
            app.ImageAxes_RegionGrowing.YTick = [];
            app.ImageAxes_kMeans = uiaxes(app.UIFigure);
            title(app.ImageAxes_kMeans, 'K Means Clustering')
            app.ImageAxes_kMeans.XTick = [];
            app.ImageAxes_kMeans.XTickLabel = {'[ ]'};
            app.ImageAxes_kMeans.YTick = [];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.LoadButton_LoadIm = uibutton(app.UIFigure, 'push');
            app.LoadButton_LoadIm.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_LoadImPushed, true);
            app.LoadButton_LoadIm.Text = 'Load Lesion Image';
            app.LoadButton_StartDiag = uibutton(app.UIFigure, 'push');
            app.LoadButton_StartDiag.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_StartDiagPushed, true);
            app.LoadButton_StartDiag.Text = 'Start Diagnosis!';
            app.LoadButton_ConfirmChoice = uibutton(app.UIFigure, 'push');
            app.LoadButton_ConfirmChoice.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_ConfirmChoicePushed, true);
            app.LoadButton_ConfirmChoice.Text = 'Confirm Selection';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results:';
            app.FinalResultsLabel = uilabel(app.UIFigure);
            app.FinalResultsLabel.Text = {'Final results will be displayed here.'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.SelectMaskDropDownLabel = uilabel(app.UIFigure);
            app.SelectMaskDropDownLabel.Text = 'Select Mask:';
            app.SelectMaskDropDown = uidropdown(app.UIFigure);
            app.SelectMaskDropDown.Items = {'Otsu''s Method', 'Adaptive Thresholding', 'Snake (Active Contour)', 'Region Growing', 'K Means Clustering'};
            app.SelectMaskDropDown.Value = 'Otsu''s Method';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1:';
            app.ClicktobeginsegmentationonceimageisloadedLabel = uilabel(app.UIFigure);
            app.ClicktobeginsegmentationonceimageisloadedLabel.Text = '2: Click to begin segmentation once image is loaded.';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'Please select the segmentation mask from the four generated below that:'; ''; '- Captures the full lesion, including faint or blurry regions.'; '- Includes lesion boundaries (often lighter in color); these are critical for diagnosing melanoma.'; '- Avoids including too much surrounding skin.'; '- Preserves the lesion’s shape; asymmetry is an important indicator for diagnosis.'};
            app.ChoiceofSegmentationMaskLabel = uilabel(app.UIFigure);
            app.ChoiceofSegmentationMaskLabel.Text = '3: Choice of Segmentation Mask.';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageHistogramsAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end